classdef ImageAcquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        ImageTab                 matlab.ui.container.Tab
        PauseContinueButton      matlab.ui.control.StateButton
        StopButton               matlab.ui.control.StateButton
        SaveButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
        SettingsTab              matlab.ui.container.Tab
        BackgroundshotCheckBox   matlab.ui.control.CheckBox
        DatasetsizeSpinner       matlab.ui.control.Spinner
        DatasetsizeSpinnerLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        current_image = 0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function start(app, event)
            
            % Initialize storage
            app.Data = struct();
            app.Data.Config = getAcquisitionConfig();
            app.Data.signal = zeros(app.Data.Config.x_size, app.Data.Config.y_size, app.DatasetsizeSpinner.Value);
            if app.BackgroundshotCheckBox.Value
                app.Data.background = zeros(app.Data.Config.x_size, app.Data.Config.y_size, app.DatasetsizeSpinner.Value);
            end
            app.current_image = 0;
            
            while ~app.StopButton.Value
                while ~app.PauseContinueButton.Value && ~app.StopButton.Value
                    tic
                    
                    % Acquire data
                    image = acquireAndorImage();
                    app.current_image = app.current_image + 1;
                    
                    % Update layout
                    imagesc(app.UIAxes, image)
                    app.UIAxes.DataAspectRatio = [1, 1, 1];
                    app.UIAxes.Title.String = sprintf('Current image: %d', app.current_image);
                    drawnow

                    % Update data storage
                    app.Data.signal(:,:,app.current_image) = image;

                    pause(0.1)
                    toc
                end

                pause(0.1)
            end

            fprintf('Acquisition stopped\n')
            app.StopButton.Value = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.UIAxes = uiaxes(app.ImageTab);
            app.UIAxes_2 = uiaxes(app.ImageTab);
            title(app.UIAxes_2, ' ')
            app.StartButton = uibutton(app.ImageTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @start, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.ImageTab, 'push');
            app.SaveButton.Text = 'Save';
            app.StopButton = uibutton(app.ImageTab, 'state');
            app.StopButton.Text = 'Stop';
            app.PauseContinueButton = uibutton(app.ImageTab, 'state');
            app.PauseContinueButton.Text = 'Pause / Continue';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.DatasetsizeSpinnerLabel = uilabel(app.SettingsTab);
            app.DatasetsizeSpinnerLabel.Text = 'Dataset size';
            app.DatasetsizeSpinner = uispinner(app.SettingsTab);
            app.DatasetsizeSpinner.ValueDisplayFormat = '%d';
            app.DatasetsizeSpinner.Value = 20;
            app.BackgroundshotCheckBox = uicheckbox(app.SettingsTab);
            app.BackgroundshotCheckBox.Text = 'Background shot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageAcquisition

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end